classdef peakPickingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        CurrentModalFrequencyEstimatesLabel  matlab.ui.control.Label
        modalFreqtab            matlab.ui.control.Table
        RemoveModeSpinner       matlab.ui.control.Spinner
        RemoveModeSpinnerLabel  matlab.ui.control.Label
        ChangeFittingBandwidthEditField  matlab.ui.control.NumericEditField
        ChangeFittingBandwidthEditFieldLabel  matlab.ui.control.Label
        modeField               matlab.ui.control.NumericEditField
        AddModeButton           matlab.ui.control.Button
        removeModeButton        matlab.ui.control.Button
        changeBandButton        matlab.ui.control.Button
        RefinedIterationButton  matlab.ui.control.Button
        addModeButton           matlab.ui.control.Button
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        ActionsLabel            matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        ModalAnalyserAppLabel   matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        PlotControlsLabel       matlab.ui.control.Label
        ClearPlotButton         matlab.ui.control.Button
        UpdatePlotButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        modalAnalyser % Store Modal analyser class
        freqRange % range of frequencies to plot
        modalPlothandle % handle for modal plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.FilenameEditField.Value = filename;
            
            % Load file
            load(fullfile(path,filename),'f','Z')
            titlestr = sprintf("Impedance data from %s",filename);

            % Plot file
            figure(app.UIFigure)
            plot(app.UIAxes,f(1:16385), 20*log10(abs(Z(1:16385))), 'b')
            xlim(app.UIAxes,[0 5000])
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Impedance / Zc (dB)')
            title(app.UIAxes,titlestr)

            % show data tips
            datacursormode(app.UIFigure,'on')

            % Start Modal Analyser
            app.modalAnalyser = modalAnalyser(0.05,10);

            % Save frequency range
            app.freqRange = f;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: AddModeButton
        function AddModeButtonPushed(app, event)
            % code taken from https://www.mathworks.com/matlabcentral/answers/392617-how-can-i-use-ginput-in-app-designer
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
            
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            [x,y,b] = ginput(1);                        % run ginput
            
            app.UIFigure.HandleVisibility = fhv;        % return original state

            % Changes value in text field
            app.modeField.Value = x;
        end

        % Value changed function: modeField
        function modeFieldValueChanged(app, event)
            value = app.modeField.Value;
            
        end

        % Button pushed function: addModeButton
        function addModeButtonPushed(app, event)
            % Add the mode
            app.modalAnalyser.addMode(app.modeField.Value);

            % Update spinnder
            if size(app.modalAnalyser.modalFrequency,1) > 2
                app.RemoveModeSpinner.Limits = [1 length(app.modalAnalyser.modalFrequency)];
            end

            % Update table
            app.modalFreqtab.PoleNumber = app.modalAnalyser.poleIdx;
            app.modalFreqtab.ModalFrequency = app.modalAnalyser.modalFrequency(app.modalAnalyser.poleIdx,1);

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.UIAxes,'on')
            app.modalPlothandle = plot(app.UIAxes,data(:,1), 20*log10(abs(data(:,2))), 'k');
            hold(app.UIAxes,'off')

        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.UIAxes,'on')
            app.modalPlothandle = plot(app.UIAxes,data(:,1), 20*log10(abs(data(:,2))), 'k');
            hold(app.UIAxes,'off')

        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

        end

        % Button pushed function: changeBandButton
        function changeBandButtonPushed(app, event)
            app.modalAnalyser.changeBandwidth(app.ChangeFittingBandwidthEditField.Value)
        end

        % Value changed function: ChangeFittingBandwidthEditField
        function ChangeFittingBandwidthEditFieldValueChanged(app, event)
            value = app.ChangeFittingBandwidthEditField.Value;
            
        end

        % Button pushed function: RefinedIterationButton
        function RefinedIterationButtonPushed(app, event)
            % Refine the identification
            app.modalAnalyser.refineIdentification();

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update the plot
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.UIAxes,'on')
            app.modalPlothandle = plot(app.UIAxes,data(:,1), 20*log10(abs(data(:,2))), 'k');
            hold(app.UIAxes,'off')

        end

        % Button pushed function: removeModeButton
        function removeModeButtonPushed(app, event)
            % Remove mode
            app.modalAnalyser.removeMode(app.RemoveModeSpinner.Value)

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update spinnder
            if size(app.modalAnalyser.modalFrequency,1) >= 2
                app.RemoveModeSpinner.Limits = [1 length(app.modalAnalyser.modalFrequency)];
            end

            % Update plot
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.UIAxes,'on')
            app.modalPlothandle = plot(app.UIAxes,data(:,1), 20*log10(abs(data(:,2))), 'k');
            hold(app.UIAxes,'off')
        end

        % Display data changed function: modalFreqtab
        function modalFreqtabDisplayDataChanged(app, event)
            newDisplayData = app.modalFreqtab.DisplayData;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {696, 696};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModalAnalyserAppLabel = uilabel(app.LeftPanel);
            app.ModalAnalyserAppLabel.Text = 'Modal Analyser App';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ActionsLabel = uilabel(app.LeftPanel);
            app.ActionsLabel.Text = 'Actions';
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.addModeButton = uibutton(app.LeftPanel, 'push');
            app.addModeButton.ButtonPushedFcn = createCallbackFcn(app, @addModeButtonPushed, true);
            app.addModeButton.Text = 'Apply';
            app.RefinedIterationButton = uibutton(app.LeftPanel, 'push');
            app.RefinedIterationButton.ButtonPushedFcn = createCallbackFcn(app, @RefinedIterationButtonPushed, true);
            app.RefinedIterationButton.Text = 'Refined Iteration';
            app.changeBandButton = uibutton(app.LeftPanel, 'push');
            app.changeBandButton.ButtonPushedFcn = createCallbackFcn(app, @changeBandButtonPushed, true);
            app.changeBandButton.Text = 'Apply';
            app.removeModeButton = uibutton(app.LeftPanel, 'push');
            app.removeModeButton.ButtonPushedFcn = createCallbackFcn(app, @removeModeButtonPushed, true);
            app.removeModeButton.Text = 'Apply';
            app.AddModeButton = uibutton(app.LeftPanel, 'push');
            app.AddModeButton.ButtonPushedFcn = createCallbackFcn(app, @AddModeButtonPushed, true);
            app.AddModeButton.Text = 'Add Mode';
            app.modeField = uieditfield(app.LeftPanel, 'numeric');
            app.modeField.ValueChangedFcn = createCallbackFcn(app, @modeFieldValueChanged, true);
            app.ChangeFittingBandwidthEditFieldLabel = uilabel(app.LeftPanel);
            app.ChangeFittingBandwidthEditFieldLabel.Text = 'Change Fitting Bandwidth';
            app.ChangeFittingBandwidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ChangeFittingBandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeFittingBandwidthEditFieldValueChanged, true);
            app.RemoveModeSpinnerLabel = uilabel(app.LeftPanel);
            app.RemoveModeSpinnerLabel.Text = 'Remove Mode';
            app.RemoveModeSpinner = uispinner(app.LeftPanel);
            app.RemoveModeSpinner.Limits = [1 2];
            app.RemoveModeSpinner.Value = 1;
            app.modalFreqtab = uitable(app.LeftPanel);
            app.modalFreqtab.ColumnName = {'PoleNumber'; 'ModalFrequency'};
            app.modalFreqtab.RowName = {};
            app.modalFreqtab.DisplayDataChangedFcn = createCallbackFcn(app, @modalFreqtabDisplayDataChanged, true);
            app.CurrentModalFrequencyEstimatesLabel = uilabel(app.LeftPanel);
            app.CurrentModalFrequencyEstimatesLabel.Text = 'Current Modal Frequency Estimates';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UpdatePlotButton = uibutton(app.RightPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.ClearPlotButton = uibutton(app.RightPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.PlotControlsLabel = uilabel(app.RightPanel);
            app.PlotControlsLabel.Text = 'Plot Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = peakPickingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end